classdef CK_Nhom < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        GiithiuTab                     matlab.ui.container.Tab
        TextArea                       matlab.ui.control.TextArea
        NHM7Label                      matlab.ui.control.Label
        NghimTab                       matlab.ui.container.Tab
        MatrnXEditField                matlab.ui.control.EditField
        MatrnXEditFieldLabel           matlab.ui.control.Label
        HmlpEditField                  matlab.ui.control.EditField
        HmlpEditFieldLabel             matlab.ui.control.Label
        TnhButton_9                    matlab.ui.control.Button
        TnhButton_8                    matlab.ui.control.Button
        SlnlpEditField                 matlab.ui.control.NumericEditField
        SlnlpEditFieldLabel            matlab.ui.control.Label
        NghimEditField                 matlab.ui.control.NumericEditField
        NghimEditFieldLabel            matlab.ui.control.Label
        SaischophpEditField            matlab.ui.control.NumericEditField
        SaischophpEditFieldLabel       matlab.ui.control.Label
        PhngphpDropDown_2              matlab.ui.control.DropDown
        PhngphpDropDown_2Label         matlab.ui.control.Label
        MatrnBEditField                matlab.ui.control.EditField
        MatrnBEditFieldLabel           matlab.ui.control.Label
        MatrnAEditField                matlab.ui.control.EditField
        MatrnAEditFieldLabel           matlab.ui.control.Label
        DngAXBLabel                    matlab.ui.control.Label
        HphngtrnhLabel                 matlab.ui.control.Label
        PhngtrnhLabel                  matlab.ui.control.Label
        PhngphpDropDown                matlab.ui.control.DropDown
        PhngphpDropDownLabel           matlab.ui.control.Label
        KhongphnlynghimEditField       matlab.ui.control.EditField
        KhongphnlynghimEditFieldLabel  matlab.ui.control.Label
        PhngtrnhEditField              matlab.ui.control.EditField
        PhngtrnhEditFieldLabel         matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        NisuyTab                       matlab.ui.container.Tab
        athcnisuyEditField             matlab.ui.control.EditField
        athcnisuyEditFieldLabel        matlab.ui.control.Label
        TnhButton_10                   matlab.ui.control.Button
        KtqunisuyEditField             matlab.ui.control.NumericEditField
        KtqunisuyEditFieldLabel        matlab.ui.control.Label
        GitrcnnisuyEditField           matlab.ui.control.NumericEditField
        GitrcnnisuyEditFieldLabel      matlab.ui.control.Label
        PhngphpDropDown_3              matlab.ui.control.DropDown
        PhngphpDropDown_3Label         matlab.ui.control.Label
        yEditField                     matlab.ui.control.EditField
        yEditFieldLabel                matlab.ui.control.Label
        xEditField                     matlab.ui.control.EditField
        xEditFieldLabel                matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        HiquyTab                       matlab.ui.container.Tab
        Hstngquanr2EditField           matlab.ui.control.NumericEditField
        Hstngquanr2EditFieldLabel      matlab.ui.control.Label
        TnhButton_11                   matlab.ui.control.Button
        KtqudonEditField               matlab.ui.control.NumericEditField
        KtqudonEditFieldLabel          matlab.ui.control.Label
        GitrcndonEditField             matlab.ui.control.NumericEditField
        GitrcndonEditFieldLabel        matlab.ui.control.Label
        PhngtrnhhiquyEditField         matlab.ui.control.EditField
        PhngtrnhhiquyEditFieldLabel    matlab.ui.control.Label
        PhngphpDropDown_4              matlab.ui.control.DropDown
        PhngphpDropDown_4Label         matlab.ui.control.Label
        yEditField_2                   matlab.ui.control.EditField
        yEditField_2Label              matlab.ui.control.Label
        xEditField_2                   matlab.ui.control.EditField
        xEditField_2Label              matlab.ui.control.Label
        UIAxes2_2                      matlab.ui.control.UIAxes
        ohmTab                         matlab.ui.container.Tab
        KtqucahmsEditField             matlab.ui.control.NumericEditField
        KtqucahmsEditFieldLabel        matlab.ui.control.Label
        TnhButton_12                   matlab.ui.control.Button
        KtqucabngxyEditField_2         matlab.ui.control.NumericEditField
        KtqucabngxyEditField_2Label    matlab.ui.control.Label
        GitrcntnhohmEditField          matlab.ui.control.NumericEditField
        GitrcntnhohmEditFieldLabel     matlab.ui.control.Label
        SaisDropDown                   matlab.ui.control.DropDown
        SaisDropDownLabel              matlab.ui.control.Label
        PhngphpDropDown_5              matlab.ui.control.DropDown
        PhngphpDropDown_5Label         matlab.ui.control.Label
        BcnhyhEditField                matlab.ui.control.NumericEditField
        BcnhyhEditFieldLabel           matlab.ui.control.Label
        HmsEditField                   matlab.ui.control.EditField
        HmsEditFieldLabel              matlab.ui.control.Label
        HocLabel                       matlab.ui.control.Label
        yEditField_3                   matlab.ui.control.EditField
        yEditField_3Label              matlab.ui.control.Label
        xEditField_3                   matlab.ui.control.EditField
        xEditField_3Label              matlab.ui.control.Label
        TchphnTab                      matlab.ui.container.Tab
        KtqucabngxyEditField_3         matlab.ui.control.NumericEditField
        KtqucabngxyEditField_3Label    matlab.ui.control.Label
        TnhButton_13                   matlab.ui.control.Button
        KtqucahmsEditField_2           matlab.ui.control.NumericEditField
        KtqucahmsEditField_2Label      matlab.ui.control.Label
        NEditField                     matlab.ui.control.NumericEditField
        NEditFieldLabel                matlab.ui.control.Label
        CnEditField                    matlab.ui.control.EditField
        CnEditFieldLabel               matlab.ui.control.Label
        PhngphpDropDown_6              matlab.ui.control.DropDown
        PhngphpDropDown_6Label         matlab.ui.control.Label
        HmsEditField_2                 matlab.ui.control.EditField
        HmsEditField_2Label            matlab.ui.control.Label
        HocLabel_2                     matlab.ui.control.Label
        yEditField_4                   matlab.ui.control.EditField
        yEditField_4Label              matlab.ui.control.Label
        xEditField_4                   matlab.ui.control.EditField
        xEditField_4Label              matlab.ui.control.Label
        ViphnTab                       matlab.ui.container.Tab
        KtquEditField_4                matlab.ui.control.EditField
        KtquEditField_4Label           matlab.ui.control.Label
        TnhButton_15                   matlab.ui.control.Button
        PhngphpDropDown_8              matlab.ui.control.DropDown
        PhngphpDropDown_8Label         matlab.ui.control.Label
        SaischophpEditField_3          matlab.ui.control.NumericEditField
        SaischophpEditField_3Label     matlab.ui.control.Label
        NEditField_3                   matlab.ui.control.NumericEditField
        NEditField_3Label              matlab.ui.control.Label
        y0EditField_2                  matlab.ui.control.NumericEditField
        y0EditField_2Label             matlab.ui.control.Label
        KhonggitrxEditField_2          matlab.ui.control.EditField
        KhonggitrxEditField_2Label     matlab.ui.control.Label
        NghimchnhxcEditField_2         matlab.ui.control.EditField
        NghimchnhxcEditField_2Label    matlab.ui.control.Label
        PhngtrnhviphnEditField_2       matlab.ui.control.EditField
        PhngtrnhviphnEditField_2Label  matlab.ui.control.Label
        UIAxes3_2                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PhngtrnhEditField
        function PhngtrnhEditFieldValueChanged(app, event)
                  
        end

        % Value changed function: KhongphnlynghimEditField
        function KhongphnlynghimEditFieldValueChanged(app, event)
                      
        end

        % Value changed function: SaischophpEditField
        function SaischophpEditFieldValueChanged(app, event)
                        
        end

        % Value changed function: PhngphpDropDown
        function PhngphpDropDownValueChanged(app, event)
                        
        end

        % Button pushed function: TnhButton_8
        function TnhButton_8Pushed(app, event)
            fxstr = app.PhngtrnhEditField.Value;
            fx = str2func(['@(x)',fxstr]);
            fp = app.HmlpEditField.Value;
            khoangphanly = str2num(app.KhongphnlynghimEditField.Value);
            saiso = app.SaischophpEditField.Value;
            a = khoangphanly(1);
            b = khoangphanly(2);
            pp = app.PhngphpDropDown.Value;
            switch pp
                case "Chia đôi"
                    [nghiem,solanlap] = chiadoi(fx,a,b,saiso);
                case "Lặp đơn"
                    [nghiem,solanlap] = lap(fx,fp,a,b,saiso);
                case "Tiếp tuyến"
                    [nghiem,solanlap] = tieptuyen(fx,a,b,saiso);
                case "Dây cung"
                    [nghiem,solanlap] = daycung(fx,a,b,saiso);
            end
            app.NghimEditField.Value = nghiem;
            app.SlnlpEditField.Value = solanlap;
            x = linspace(a,b,400);
            y = fx(x);
            plot(app.UIAxes,x,y);
            grid(app.UIAxes,'on');
            hold(app.UIAxes,'on');
            yline(app.UIAxes,0,'-k');
            hold(app.UIAxes,'on');
            plot(app.UIAxes,nghiem,fx(nghiem),'or');
            legend(app.UIAxes,'f(x)','y = 0','Nghiệm')
            hold(app.UIAxes,'off');
        end

        % Value changed function: HmlpEditField
        function HmlpEditFieldValueChanged(app, event)
                       
        end

        % Value changed function: NghimEditField
        function NghimEditFieldValueChanged(app, event)
                        
        end

        % Value changed function: SlnlpEditField
        function SlnlpEditFieldValueChanged(app, event)
                       
        end

        % Value changed function: MatrnAEditField
        function MatrnAEditFieldValueChanged(app, event)
                    
        end

        % Value changed function: MatrnBEditField
        function MatrnBEditFieldValueChanged(app, event)
                        
        end

        % Value changed function: PhngphpDropDown_2
        function PhngphpDropDown_2ValueChanged(app, event)
                       
        end

        % Button pushed function: TnhButton_9
        function TnhButton_9Pushed(app, event)
            A = str2num(app.MatrnAEditField.Value);
            b = str2num(app.MatrnBEditField.Value);
            pp = app.PhngphpDropDown_2.Value;
            switch pp
                case "Gauss"
                    x = Gauss(A,b);
                case "LU"
                    [L U] = LUdec(A);
                    x = LUfact(L,U,b);
            end
            app.MatrnXEditField.Value = mat2str(x);
        end

        % Value changed function: MatrnXEditField
        function MatrnXEditFieldValueChanged(app, event)
                        
        end

        % Value changed function: xEditField
        function xEditFieldValueChanged(app, event)
                        
        end

        % Value changed function: yEditField
        function yEditFieldValueChanged(app, event)
                        
        end

        % Value changed function: GitrcnnisuyEditField
        function GitrcnnisuyEditFieldValueChanged(app, event)
                       
        end

        % Value changed function: PhngphpDropDown_3
        function PhngphpDropDown_3ValueChanged(app, event)
                    
        end

        % Button pushed function: TnhButton_10
        function TnhButton_10Pushed(app, event)
            xa = str2num(app.xEditField.Value);
            ya = str2num(app.yEditField.Value);
            x = app.GitrcnnisuyEditField.Value;
            pp = app.PhngphpDropDown_3.Value;
            switch pp
                case "Lagrange"
                    result = Lagrange(xa,ya,x);                   
                    P = LagrangeSymbolic(xa,ya);                  
                case "Newton"
                    result = NewtonInterpolation(xa,ya,x);
                    P = NewtonSymbolic(xa,ya);
            end
            app.KtqunisuyEditField.Value = result;
            app.athcnisuyEditField.Value = char(P);
            xx = linspace(min(xa),max(xa),200);
            Pi = matlabFunction(P);
            plot(app.UIAxes2,xa,ya,'or');
            hold(app.UIAxes2,'on');
            grid(app.UIAxes2,'on');
            plot(app.UIAxes2,xx,Pi(xx),'-b');
            hold(app.UIAxes2,'on');
            plot(app.UIAxes2,x,result,'*r');
            legend(app.UIAxes2,'Dữ liệu thực','Đa thức nội suy','Kết quả nội suy');
            hold(app.UIAxes2,'off');
        end

        % Value changed function: KtqunisuyEditField
        function KtqunisuyEditFieldValueChanged(app, event)
                       
        end

        % Value changed function: xEditField_2
        function xEditField_2ValueChanged(app, event)
                        
        end

        % Value changed function: yEditField_2
        function yEditField_2ValueChanged(app, event)
                        
        end

        % Value changed function: GitrcndonEditField
        function GitrcndonEditFieldValueChanged(app, event)
                        
        end

        % Value changed function: PhngphpDropDown_4
        function PhngphpDropDown_4ValueChanged(app, event)
                        
        end

        % Button pushed function: TnhButton_11
        function TnhButton_11Pushed(app, event)
            xi = str2num(app.xEditField_2.Value);
            yi = str2num(app.yEditField_2.Value);
            x = app.GitrcndonEditField.Value;
            pp = app.PhngphpDropDown_4.Value;
            switch pp
                case "Tuyến tính"
                    [a,b,r2] = linearregress(xi,yi,x);
                    result = a + b*x;
                    app.PhngtrnhhiquyEditField.Value = sprintf('y = %f + %f*x',a,b);
                    xx = linspace(min(xi),max(xi),200);
                    yy = a + b*xx;
                    plot(app.UIAxes2_2,xi,yi,'ob');
                    hold(app.UIAxes2_2,'on');
                    grid(app.UIAxes2_2,'on');
                    plot(app.UIAxes2_2,xx,yy,'-k');
                    hold(app.UIAxes2_2,'on');
                    plot(app.UIAxes2_2,x,result,'*r');
                    legend(app.UIAxes2_2,'Tuyến tính','Chính xác','Kết quả dự đoán');
                    hold(app.UIAxes2_2,'off');
                case "Hàm mũ a*x^b"
                    [a,b,r2] = xpower(xi,yi);
                    result = a*x^b;
                    app.PhngtrnhhiquyEditField.Value = sprintf('y = %f*x^%f',a,b);
                    xx = linspace(min(xi),max(xi),200);
                    yy = a*xx.^b;
                    plot(app.UIAxes2_2,xi,yi,'ob');
                    hold(app.UIAxes2_2,'on');
                    grid(app.UIAxes2_2,'on');
                    plot(app.UIAxes2_2,xx,yy,'-k');
                    hold(app.UIAxes2_2,'on');
                    plot(app.UIAxes2_2,x,result,'*r');
                    legend(app.UIAxes2_2,'Hàm mũ','Chính xác','Kết quả dự đoán');
                    hold(app.UIAxes2_2,'off');
                case "Hàm mũ a*e^(b*x)"
                    [a,b,r2] = epower(xi,yi);
                    result = a*exp(b*x);
                    app.PhngtrnhhiquyEditField.Value = sprintf('y = %f*e^(%f*x)',a,b);
                    xx = linspace(min(xi),max(xi),200);
                    yy = a*exp(b*xx);
                    plot(app.UIAxes2_2,xi,yi,'ob');
                    hold(app.UIAxes2_2,'on');
                    grid(app.UIAxes2_2,'on');
                    plot(app.UIAxes2_2,xx,yy,'-k');
                    hold(app.UIAxes2_2,'on');
                    plot(app.UIAxes2_2,x,result,'*r');
                    legend(app.UIAxes2_2,'Hàm mũ','Chính xác','Kết quả dự đoán');
                    hold(app.UIAxes2_2,'off');
                case "Hàm logarit a + b*log10(x)"
                    [a,b,r2] = logarit10(xi,yi)
                    result = a + b*log10(x);
                    app.PhngtrnhhiquyEditField.Value = sprintf('y = %f + %f*log10(x)',a,b);
                    xx = linspace(min(xi),max(xi),200);
                    yy = a + b*log10(xx);
                    plot(app.UIAxes2_2,xi,yi,'ob');
                    hold(app.UIAxes2_2,'on');
                    grid(app.UIAxes2_2,'on');
                    plot(app.UIAxes2_2,xx,yy,'-k');
                    hold(app.UIAxes2_2,'on');
                    plot(app.UIAxes2_2,x,result,'*r');
                    legend(app.UIAxes2_2,'Hàm logarit','Chính xác','Kết quả dự đoán');
                    hold(app.UIAxes2_2,'off');
                case "Hàm logarit a + b*ln(x)"
                    [a,b,r2] = logarite(xi,yi)
                    result = a + b*log(x);
                    app.PhngtrnhhiquyEditField.Value = sprintf('y = %f + %f*ln(x)',a,b);
                    xx = linspace(min(xi),max(xi),200);
                    yy = a + b*log(xx);
                    plot(app.UIAxes2_2,xi,yi,'ob');
                    hold(app.UIAxes2_2,'on');
                    grid(app.UIAxes2_2,'on');
                    plot(app.UIAxes2_2,xx,yy,'-k');
                    hold(app.UIAxes2_2,'on');
                    plot(app.UIAxes2_2,x,result,'*r');
                    legend(app.UIAxes2_2,'Hàm logarit','Chính xác','Kết quả dự đoán');
                    hold(app.UIAxes2_2,'off');
            end
            app.KtqudonEditField.Value = result;
            app.Hstngquanr2EditField.Value = r2;
        end

        % Value changed function: Hstngquanr2EditField
        function Hstngquanr2EditFieldValueChanged(app, event)
                        
        end

        % Value changed function: KtqudonEditField
        function KtqudonEditFieldValueChanged(app, event)
                        
        end

        % Value changed function: PhngtrnhhiquyEditField
        function PhngtrnhhiquyEditFieldValueChanged(app, event)
                      
        end

        % Callback function
        function TnhButton_14Pushed(app, event)
            fxy = app.PhngtrnhviphnEditField.Value;
            nghiemchinhxac = app.NghimchnhxcEditField.Value;
            nghiemchinhxaci = str2func(['@(x)',nghiemchinhxac]);
            khoanggiatrix = str2num(app.KhonggitrxEditField.Value);
            xdau = khoanggiatrix(1);
            xcuoi = khoanggiatrix(2);
            y0 = app.y0EditField.Value;
            N = app.NEditField.Value;
            saiso = app.SaischophpEditField_2.Value;
            pp = app.PhngphpDropDown_7.Value;
            switch pp
                case "Euler"
                    [x,y] = Ole(fxy,xdau,xcuoi,y0,N);
                    app.KtquEditField_3.Value = mat2str(y);
                    plot(app.UIAxes3,x,y,'or');
                    hold(app.UIAxes3,'on');
                    grid(app.UIAxes3,'on');
                    plot(app.UIAxes3,x,nghiemchinhxaci(x),'-b');
                    legend(app.UIAxes3,'Euler','Chính xác');
                    hold(app.UIAxes3,'off');
                case "Hình thang"
                    [x,y] = HienAnHinhThang(fxy,xdau,xcuoi,y0,N,saiso);
                    app.KtquEditField_3.Value = mat2str(y);
                    plot(app.UIAxes3,x,y,'or');
                    hold(app.UIAxes3,'on');
                    grid(app.UIAxes3,'on');
                    plot(app.UIAxes3,x,nghiemchinhxaci(x),'-b');
                    legend(app.UIAxes3,'Hình thang','Chính xác');
                    hold(app.UIAxes3,'off');
                case "Trung điểm"
                    [x,y] = HienAnTrungDiem(fxy,xdau,xcuoi,y0,N);
                    app.KtquEditField_3.Value = mat2str(y);
                    plot(app.UIAxes3,x,y,'or');
                    hold(app.UIAxes3,'on');
                    grid(app.UIAxes3,'on');
                    plot(app.UIAxes3,x,nghiemchinhxaci(x),'-b');
                    legend(app.UIAxes3,'Trung điểm','Chính xác');
                    hold(app.UIAxes3,'off');
                case "Runge-Kutta"
                    [x,y] = RK(fxy,xdau,xcuoi,y0,N);
                    app.KtquEditField_3.Value = mat2str(y);
                    plot(app.UIAxes3,x,y,'or');
                    hold(app.UIAxes3,'on');
                    grid(app.UIAxes3,'on');
                    plot(app.UIAxes3,x,nghiemchinhxaci(x),'-b');
                    legend(app.UIAxes3,'Runge-Kutta','Chính xác');
                    hold(app.UIAxes3,'off');
            end
        end

        % Value changed function: PhngtrnhviphnEditField_2
        function PhngtrnhviphnEditField_2ValueChanged(app, event)
                        
        end

        % Value changed function: NghimchnhxcEditField_2
        function NghimchnhxcEditField_2ValueChanged(app, event)
                       
        end

        % Value changed function: KhonggitrxEditField_2
        function KhonggitrxEditField_2ValueChanged(app, event)
            
        end

        % Value changed function: y0EditField_2
        function y0EditField_2ValueChanged(app, event)
                        
        end

        % Value changed function: NEditField_3
        function NEditField_3ValueChanged(app, event)
                        
        end

        % Value changed function: SaischophpEditField_3
        function SaischophpEditField_3ValueChanged(app, event)
                        
        end

        % Value changed function: PhngphpDropDown_8
        function PhngphpDropDown_8ValueChanged(app, event)
                       
        end

        % Button pushed function: TnhButton_15
        function TnhButton_15Pushed(app, event)
            fxy = app.PhngtrnhviphnEditField_2.Value;
            nghiemchinhxac = app.NghimchnhxcEditField_2.Value;
            nghiemchinhxaci = str2func(['@(x)',nghiemchinhxac]);
            khoanggiatrix = str2num(app.KhonggitrxEditField_2.Value);
            xdau = khoanggiatrix(1);
            xcuoi = khoanggiatrix(2);
            y0 = app.y0EditField_2.Value;
            N = app.NEditField_3.Value;
            saiso = app.SaischophpEditField_3.Value;
            pp = app.PhngphpDropDown_8.Value;
            switch pp
                case "Euler"
                    [x,y] = Ole(fxy,xdau,xcuoi,y0,N);
                    app.KtquEditField_4.Value = mat2str(y);
                    plot(app.UIAxes3_2,x,y,'or');
                    hold(app.UIAxes3_2,'on');
                    grid(app.UIAxes3_2,'on');
                    plot(app.UIAxes3_2,x,nghiemchinhxaci(x),'-b');
                    legend(app.UIAxes3_2,'Euler','Chính xác');
                    hold(app.UIAxes3_2,'off');
                case "Hình thang"
                    [x,y] = HienAnHinhThang(fxy,xdau,xcuoi,y0,N,saiso);
                    app.KtquEditField_4.Value = mat2str(y);
                    plot(app.UIAxes3_2,x,y,'or');
                    hold(app.UIAxes3_2,'on');
                    grid(app.UIAxes3_2,'on');
                    plot(app.UIAxes3_2,x,nghiemchinhxaci(x),'-b');
                    legend(app.UIAxes3_2,'Hình thang','Chính xác');
                    hold(app.UIAxes3_2,'off');
                case "Trung điểm"
                    [x,y] = HienAnTrungDiem(fxy,xdau,xcuoi,y0,N);
                    app.KtquEditField_4.Value = mat2str(y);
                    plot(app.UIAxes3_2,x,y,'or');
                    hold(app.UIAxes3_2,'on');
                    grid(app.UIAxes3_2,'on');
                    plot(app.UIAxes3_2,x,nghiemchinhxaci(x),'-b');
                    legend(app.UIAxes3_2,'Trung điểm','Chính xác');
                    hold(app.UIAxes3_2,'off');
                case "Runge-Kutta"
                    [x,y] = RK(fxy,xdau,xcuoi,y0,N);
                    app.KtquEditField_4.Value = mat2str(y);
                    plot(app.UIAxes3_2,x,y,'or');
                    hold(app.UIAxes3_2,'on');
                    grid(app.UIAxes3_2,'on');
                    plot(app.UIAxes3_2,x,nghiemchinhxaci(x),'-b');
                    legend(app.UIAxes3_2,'Runge-Kutta','Chính xác');
                    hold(app.UIAxes3_2,'off');
            end
        end

        % Value changed function: KtquEditField_4
        function KtquEditField_4ValueChanged(app, event)
                       
        end

        % Value changed function: xEditField_4
        function xEditField_4ValueChanged(app, event)
                        
        end

        % Value changed function: yEditField_4
        function yEditField_4ValueChanged(app, event)
                       
        end

        % Value changed function: PhngphpDropDown_6
        function PhngphpDropDown_6ValueChanged(app, event)
                       
        end

        % Value changed function: CnEditField
        function CnEditFieldValueChanged(app, event)
                       
        end

        % Value changed function: NEditField
        function NEditFieldValueChanged(app, event)
                       
        end

        % Button pushed function: TnhButton_13
        function TnhButton_13Pushed(app, event)
            xi = str2num(app.xEditField_4.Value);
            yi = str2num(app.yEditField_4.Value);
            fx = app.HmsEditField_2.Value;
            pp = app.PhngphpDropDown_6.Value;
            can = str2num(app.CnEditField.Value); 
            a = can(1);
            b = can(2);
            N = app.NEditField.Value; 
            coBang = ~isempty(xi) && ~isempty(yi);
            coHam  = ~isempty(fx) && ~isempty(can) && length(can)==2 && ~isnan(N);
            Iham = 0; 
            Ibang = 0;
            if coHam
                a = can(1); b = can(2);
                switch pp
                    case "Hình thang"
                        Iham = TichphanHinhthang(fx,a,b,N);
                    case "Simpson 1/3"
                        Iham = TichphanSimpson(fx,a,b,N);
                    case "Simpson 3/8"
                        Iham = TichphanSimpson38(fx,a,b,N);
                end
            end
            if coBang
                switch pp
                    case "Hình thang"
                        Ibang = TPHinhthang(xi,yi);
                    case "Simpson 1/3"
                        Ibang = TichphanSimpson13(xi,yi);
                    case "Simpson 3/8"
                        Ibang = Simpson38(xi,yi);
                end
            end
            app.KtqucahmsEditField_2.Value = double(Iham);
            app.KtqucabngxyEditField_3.Value = double(Ibang);
        end

        % Value changed function: HmsEditField_2
        function HmsEditField_2ValueChanged(app, event)
                        
        end

        % Value changed function: KtqucahmsEditField_2
        function KtqucahmsEditField_2ValueChanged(app, event)
                       
        end

        % Value changed function: xEditField_3
        function xEditField_3ValueChanged(app, event)
                        
        end

        % Value changed function: yEditField_3
        function yEditField_3ValueChanged(app, event)
                        
        end

        % Value changed function: HmsEditField
        function HmsEditFieldValueChanged(app, event)
                        
        end

        % Value changed function: BcnhyhEditField
        function BcnhyhEditFieldValueChanged(app, event)
                        
        end

        % Value changed function: PhngphpDropDown_5
        function PhngphpDropDown_5ValueChanged(app, event)
                     
        end

        % Value changed function: SaisDropDown
        function SaisDropDownValueChanged(app, event)
                        
        end

        % Value changed function: GitrcntnhohmEditField
        function GitrcntnhohmEditFieldValueChanged(app, event)
                        
        end

        % Button pushed function: TnhButton_12
        function TnhButton_12Pushed(app, event)
            xi = str2num(app.xEditField_3.Value);
            yi = str2num(app.yEditField_3.Value);
            f = app.HmsEditField.Value;
            h = app.BcnhyhEditField.Value;
            phuongphap = app.PhngphpDropDown_5.Value;
            saiso = app.SaisDropDown.Value;
            x = app.GitrcntnhohmEditField.Value;
            if ~isempty(xi) && ~isempty(yi)
                result = tinh_dao_ham_theo_mang_du_lieu(xi, yi, x, phuongphap, saiso);
                app.KtqucabngxyEditField_2.Value = result;
                app.KtqucahmsEditField.Value = 0;
            elseif ~isempty(f)
                fx = str2func(['@(x)',f]);
                result = ham_tinh_hamso(fx, x, h, phuongphap, saiso);
                app.KtqucahmsEditField.Value = result;
                app.KtqucabngxyEditField_2.Value = 0;
            end
        end

        % Value changed function: KtqucabngxyEditField_2
        function KtqucabngxyEditField_2ValueChanged(app, event)
                      
        end

        % Value changed function: KtqucahmsEditField
        function KtqucahmsEditFieldValueChanged(app, event)
                        
        end

        % Value changed function: athcnisuyEditField
        function athcnisuyEditFieldValueChanged(app, event)
               
        end

        % Value changed function: KtqucabngxyEditField_3
        function KtqucabngxyEditField_3ValueChanged(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GiithiuTab = uitab(app.TabGroup);
            app.GiithiuTab.Title = 'Giới thiệu';
            app.NHM7Label = uilabel(app.GiithiuTab);
            app.NHM7Label.Text = 'NHÓM 7';
            app.TextArea = uitextarea(app.GiithiuTab);
            app.TextArea.Value = {'Thành viên:'; '1. Phạm Thái Cẩm Nhung - 23207091'; '2. Phan Phi Phàm - 23207092'; '3. Phạm Mai Diệu Thảo - 23207109'; '4. Nguyễn Trí Thức - 23207115'; '5. Mạc Quang Khải - 23207130'; '6. Lê Khánh Linh - 23207132'; ' '};
            app.NghimTab = uitab(app.TabGroup);
            app.NghimTab.Title = 'Nghiệm';
            app.UIAxes = uiaxes(app.NghimTab);
            title(app.UIAxes, 'Đồ thị hàm số')
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'y')
            zlabel(app.UIAxes, 'Z')
            app.PhngtrnhEditFieldLabel = uilabel(app.NghimTab);
            app.PhngtrnhEditFieldLabel.Text = 'Phương trình';
            app.PhngtrnhEditField = uieditfield(app.NghimTab, 'text');
            app.PhngtrnhEditField.ValueChangedFcn = createCallbackFcn(app, @PhngtrnhEditFieldValueChanged, true);
            app.KhongphnlynghimEditFieldLabel = uilabel(app.NghimTab);
            app.KhongphnlynghimEditFieldLabel.Text = 'Khoảng phân ly nghiệm';
            app.KhongphnlynghimEditField = uieditfield(app.NghimTab, 'text');
            app.KhongphnlynghimEditField.ValueChangedFcn = createCallbackFcn(app, @KhongphnlynghimEditFieldValueChanged, true);
            app.PhngphpDropDownLabel = uilabel(app.NghimTab);
            app.PhngphpDropDownLabel.Text = 'Phương pháp';
            app.PhngphpDropDown = uidropdown(app.NghimTab);
            app.PhngphpDropDown.Items = {'Chia đôi', 'Lặp đơn', 'Tiếp tuyến', 'Dây cung'};
            app.PhngphpDropDown.ValueChangedFcn = createCallbackFcn(app, @PhngphpDropDownValueChanged, true);
            app.PhngphpDropDown.Value = 'Chia đôi';
            app.PhngtrnhLabel = uilabel(app.NghimTab);
            app.PhngtrnhLabel.Text = 'Phương trình';
            app.HphngtrnhLabel = uilabel(app.NghimTab);
            app.HphngtrnhLabel.Text = 'Hệ phương trình';
            app.DngAXBLabel = uilabel(app.NghimTab);
            app.DngAXBLabel.Text = 'Dạng A.X = B';
            app.MatrnAEditFieldLabel = uilabel(app.NghimTab);
            app.MatrnAEditFieldLabel.Text = 'Ma trận A';
            app.MatrnAEditField = uieditfield(app.NghimTab, 'text');
            app.MatrnAEditField.ValueChangedFcn = createCallbackFcn(app, @MatrnAEditFieldValueChanged, true);
            app.MatrnBEditFieldLabel = uilabel(app.NghimTab);
            app.MatrnBEditFieldLabel.Text = 'Ma trận B';
            app.MatrnBEditField = uieditfield(app.NghimTab, 'text');
            app.MatrnBEditField.ValueChangedFcn = createCallbackFcn(app, @MatrnBEditFieldValueChanged, true);
            app.PhngphpDropDown_2Label = uilabel(app.NghimTab);
            app.PhngphpDropDown_2Label.Text = 'Phương pháp';
            app.PhngphpDropDown_2 = uidropdown(app.NghimTab);
            app.PhngphpDropDown_2.Items = {'Gauss', 'LU'};
            app.PhngphpDropDown_2.ValueChangedFcn = createCallbackFcn(app, @PhngphpDropDown_2ValueChanged, true);
            app.PhngphpDropDown_2.Value = 'Gauss';
            app.SaischophpEditFieldLabel = uilabel(app.NghimTab);
            app.SaischophpEditFieldLabel.Text = 'Sai số cho phép';
            app.SaischophpEditField = uieditfield(app.NghimTab, 'numeric');
            app.SaischophpEditField.ValueChangedFcn = createCallbackFcn(app, @SaischophpEditFieldValueChanged, true);
            app.NghimEditFieldLabel = uilabel(app.NghimTab);
            app.NghimEditFieldLabel.Text = 'Nghiệm';
            app.NghimEditField = uieditfield(app.NghimTab, 'numeric');
            app.NghimEditField.ValueChangedFcn = createCallbackFcn(app, @NghimEditFieldValueChanged, true);
            app.SlnlpEditFieldLabel = uilabel(app.NghimTab);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.SlnlpEditField = uieditfield(app.NghimTab, 'numeric');
            app.SlnlpEditField.ValueChangedFcn = createCallbackFcn(app, @SlnlpEditFieldValueChanged, true);
            app.TnhButton_8 = uibutton(app.NghimTab, 'push');
            app.TnhButton_8.ButtonPushedFcn = createCallbackFcn(app, @TnhButton_8Pushed, true);
            app.TnhButton_8.Text = 'Tính';
            app.TnhButton_9 = uibutton(app.NghimTab, 'push');
            app.TnhButton_9.ButtonPushedFcn = createCallbackFcn(app, @TnhButton_9Pushed, true);
            app.TnhButton_9.Text = 'Tính';
            app.HmlpEditFieldLabel = uilabel(app.NghimTab);
            app.HmlpEditFieldLabel.Text = 'Hàm lặp';
            app.HmlpEditField = uieditfield(app.NghimTab, 'text');
            app.HmlpEditField.ValueChangedFcn = createCallbackFcn(app, @HmlpEditFieldValueChanged, true);
            app.MatrnXEditFieldLabel = uilabel(app.NghimTab);
            app.MatrnXEditFieldLabel.Text = 'Ma trận X';
            app.MatrnXEditField = uieditfield(app.NghimTab, 'text');
            app.MatrnXEditField.ValueChangedFcn = createCallbackFcn(app, @MatrnXEditFieldValueChanged, true);
            app.NisuyTab = uitab(app.TabGroup);
            app.NisuyTab.Title = 'Nội suy';
            app.UIAxes2 = uiaxes(app.NisuyTab);
            title(app.UIAxes2, 'Đồ thị hàm số')
            xlabel(app.UIAxes2, 'x')
            ylabel(app.UIAxes2, 'y')
            zlabel(app.UIAxes2, 'Z')
            app.xEditFieldLabel = uilabel(app.NisuyTab);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.NisuyTab, 'text');
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.yEditFieldLabel = uilabel(app.NisuyTab);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.NisuyTab, 'text');
            app.yEditField.ValueChangedFcn = createCallbackFcn(app, @yEditFieldValueChanged, true);
            app.PhngphpDropDown_3Label = uilabel(app.NisuyTab);
            app.PhngphpDropDown_3Label.Text = 'Phương pháp';
            app.PhngphpDropDown_3 = uidropdown(app.NisuyTab);
            app.PhngphpDropDown_3.Items = {'Lagrange', 'Newton'};
            app.PhngphpDropDown_3.ValueChangedFcn = createCallbackFcn(app, @PhngphpDropDown_3ValueChanged, true);
            app.PhngphpDropDown_3.Value = 'Lagrange';
            app.GitrcnnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.GitrcnnisuyEditFieldLabel.Text = 'Giá trị cần nội suy';
            app.GitrcnnisuyEditField = uieditfield(app.NisuyTab, 'numeric');
            app.GitrcnnisuyEditField.ValueChangedFcn = createCallbackFcn(app, @GitrcnnisuyEditFieldValueChanged, true);
            app.KtqunisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.KtqunisuyEditFieldLabel.Text = 'Kết quả nội suy';
            app.KtqunisuyEditField = uieditfield(app.NisuyTab, 'numeric');
            app.KtqunisuyEditField.ValueChangedFcn = createCallbackFcn(app, @KtqunisuyEditFieldValueChanged, true);
            app.TnhButton_10 = uibutton(app.NisuyTab, 'push');
            app.TnhButton_10.ButtonPushedFcn = createCallbackFcn(app, @TnhButton_10Pushed, true);
            app.TnhButton_10.Text = 'Tính';
            app.athcnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.athcnisuyEditFieldLabel.Text = 'Đa thức nội suy';
            app.athcnisuyEditField = uieditfield(app.NisuyTab, 'text');
            app.athcnisuyEditField.ValueChangedFcn = createCallbackFcn(app, @athcnisuyEditFieldValueChanged, true);
            app.HiquyTab = uitab(app.TabGroup);
            app.HiquyTab.Title = 'Hồi quy';
            app.UIAxes2_2 = uiaxes(app.HiquyTab);
            title(app.UIAxes2_2, 'Đồ thị hàm số')
            xlabel(app.UIAxes2_2, 'x')
            ylabel(app.UIAxes2_2, 'y')
            zlabel(app.UIAxes2_2, 'Z')
            app.xEditField_2Label = uilabel(app.HiquyTab);
            app.xEditField_2Label.Text = 'x';
            app.xEditField_2 = uieditfield(app.HiquyTab, 'text');
            app.xEditField_2.ValueChangedFcn = createCallbackFcn(app, @xEditField_2ValueChanged, true);
            app.yEditField_2Label = uilabel(app.HiquyTab);
            app.yEditField_2Label.Text = 'y';
            app.yEditField_2 = uieditfield(app.HiquyTab, 'text');
            app.yEditField_2.ValueChangedFcn = createCallbackFcn(app, @yEditField_2ValueChanged, true);
            app.PhngphpDropDown_4Label = uilabel(app.HiquyTab);
            app.PhngphpDropDown_4Label.Text = 'Phương pháp';
            app.PhngphpDropDown_4 = uidropdown(app.HiquyTab);
            app.PhngphpDropDown_4.Items = {'Tuyến tính', 'Hàm mũ a*x^b', 'Hàm mũ a*e^(b*x)', 'Hàm logarit a + b*log10(x)', 'Hàm logarit a + b*ln(x)'};
            app.PhngphpDropDown_4.ValueChangedFcn = createCallbackFcn(app, @PhngphpDropDown_4ValueChanged, true);
            app.PhngphpDropDown_4.Value = 'Tuyến tính';
            app.PhngtrnhhiquyEditFieldLabel = uilabel(app.HiquyTab);
            app.PhngtrnhhiquyEditFieldLabel.Text = 'Phương trình hồi quy';
            app.PhngtrnhhiquyEditField = uieditfield(app.HiquyTab, 'text');
            app.PhngtrnhhiquyEditField.ValueChangedFcn = createCallbackFcn(app, @PhngtrnhhiquyEditFieldValueChanged, true);
            app.GitrcndonEditFieldLabel = uilabel(app.HiquyTab);
            app.GitrcndonEditFieldLabel.Text = 'Giá trị cần dự đoán';
            app.GitrcndonEditField = uieditfield(app.HiquyTab, 'numeric');
            app.GitrcndonEditField.ValueChangedFcn = createCallbackFcn(app, @GitrcndonEditFieldValueChanged, true);
            app.KtqudonEditFieldLabel = uilabel(app.HiquyTab);
            app.KtqudonEditFieldLabel.Text = 'Kết quả dự đoán';
            app.KtqudonEditField = uieditfield(app.HiquyTab, 'numeric');
            app.KtqudonEditField.ValueChangedFcn = createCallbackFcn(app, @KtqudonEditFieldValueChanged, true);
            app.TnhButton_11 = uibutton(app.HiquyTab, 'push');
            app.TnhButton_11.ButtonPushedFcn = createCallbackFcn(app, @TnhButton_11Pushed, true);
            app.TnhButton_11.Text = 'Tính';
            app.Hstngquanr2EditFieldLabel = uilabel(app.HiquyTab);
            app.Hstngquanr2EditFieldLabel.Text = 'Hệ số tương quan r^2';
            app.Hstngquanr2EditField = uieditfield(app.HiquyTab, 'numeric');
            app.Hstngquanr2EditField.ValueChangedFcn = createCallbackFcn(app, @Hstngquanr2EditFieldValueChanged, true);
            app.ohmTab = uitab(app.TabGroup);
            app.ohmTab.Title = 'Đạo hàm';
            app.xEditField_3Label = uilabel(app.ohmTab);
            app.xEditField_3Label.Text = 'x';
            app.xEditField_3 = uieditfield(app.ohmTab, 'text');
            app.xEditField_3.ValueChangedFcn = createCallbackFcn(app, @xEditField_3ValueChanged, true);
            app.yEditField_3Label = uilabel(app.ohmTab);
            app.yEditField_3Label.Text = 'y';
            app.yEditField_3 = uieditfield(app.ohmTab, 'text');
            app.yEditField_3.ValueChangedFcn = createCallbackFcn(app, @yEditField_3ValueChanged, true);
            app.HocLabel = uilabel(app.ohmTab);
            app.HocLabel.Text = 'Hoặc';
            app.HmsEditFieldLabel = uilabel(app.ohmTab);
            app.HmsEditFieldLabel.Text = 'Hàm số';
            app.HmsEditField = uieditfield(app.ohmTab, 'text');
            app.HmsEditField.ValueChangedFcn = createCallbackFcn(app, @HmsEditFieldValueChanged, true);
            app.BcnhyhEditFieldLabel = uilabel(app.ohmTab);
            app.BcnhyhEditFieldLabel.Text = 'Bước nhảy (h)';
            app.BcnhyhEditField = uieditfield(app.ohmTab, 'numeric');
            app.BcnhyhEditField.ValueChangedFcn = createCallbackFcn(app, @BcnhyhEditFieldValueChanged, true);
            app.PhngphpDropDown_5Label = uilabel(app.ohmTab);
            app.PhngphpDropDown_5Label.Text = 'Phương pháp';
            app.PhngphpDropDown_5 = uidropdown(app.ohmTab);
            app.PhngphpDropDown_5.Items = {'Xấp xỉ tiến', 'Xấp xỉ lùi', 'Xấp xỉ trung tâm'};
            app.PhngphpDropDown_5.ValueChangedFcn = createCallbackFcn(app, @PhngphpDropDown_5ValueChanged, true);
            app.PhngphpDropDown_5.Value = 'Xấp xỉ tiến';
            app.SaisDropDownLabel = uilabel(app.ohmTab);
            app.SaisDropDownLabel.Text = 'Sai số';
            app.SaisDropDown = uidropdown(app.ohmTab);
            app.SaisDropDown.Items = {'O(h)', 'O(h^2)'};
            app.SaisDropDown.ValueChangedFcn = createCallbackFcn(app, @SaisDropDownValueChanged, true);
            app.SaisDropDown.Value = 'O(h)';
            app.GitrcntnhohmEditFieldLabel = uilabel(app.ohmTab);
            app.GitrcntnhohmEditFieldLabel.Text = 'Giá trị cần tính đạo hàm';
            app.GitrcntnhohmEditField = uieditfield(app.ohmTab, 'numeric');
            app.GitrcntnhohmEditField.ValueChangedFcn = createCallbackFcn(app, @GitrcntnhohmEditFieldValueChanged, true);
            app.KtqucabngxyEditField_2Label = uilabel(app.ohmTab);
            app.KtqucabngxyEditField_2Label.Text = 'Kết quả của bảng x,y';
            app.KtqucabngxyEditField_2 = uieditfield(app.ohmTab, 'numeric');
            app.KtqucabngxyEditField_2.ValueChangedFcn = createCallbackFcn(app, @KtqucabngxyEditField_2ValueChanged, true);
            app.TnhButton_12 = uibutton(app.ohmTab, 'push');
            app.TnhButton_12.ButtonPushedFcn = createCallbackFcn(app, @TnhButton_12Pushed, true);
            app.TnhButton_12.Text = 'Tính';
            app.KtqucahmsEditFieldLabel = uilabel(app.ohmTab);
            app.KtqucahmsEditFieldLabel.Text = 'Kết quả của hàm số';
            app.KtqucahmsEditField = uieditfield(app.ohmTab, 'numeric');
            app.KtqucahmsEditField.ValueChangedFcn = createCallbackFcn(app, @KtqucahmsEditFieldValueChanged, true);
            app.TchphnTab = uitab(app.TabGroup);
            app.TchphnTab.Title = 'Tích phân';
            app.xEditField_4Label = uilabel(app.TchphnTab);
            app.xEditField_4Label.Text = 'x';
            app.xEditField_4 = uieditfield(app.TchphnTab, 'text');
            app.xEditField_4.ValueChangedFcn = createCallbackFcn(app, @xEditField_4ValueChanged, true);
            app.yEditField_4Label = uilabel(app.TchphnTab);
            app.yEditField_4Label.Text = 'y';
            app.yEditField_4 = uieditfield(app.TchphnTab, 'text');
            app.yEditField_4.ValueChangedFcn = createCallbackFcn(app, @yEditField_4ValueChanged, true);
            app.HocLabel_2 = uilabel(app.TchphnTab);
            app.HocLabel_2.Text = 'Hoặc';
            app.HmsEditField_2Label = uilabel(app.TchphnTab);
            app.HmsEditField_2Label.Text = 'Hàm số';
            app.HmsEditField_2 = uieditfield(app.TchphnTab, 'text');
            app.HmsEditField_2.ValueChangedFcn = createCallbackFcn(app, @HmsEditField_2ValueChanged, true);
            app.PhngphpDropDown_6Label = uilabel(app.TchphnTab);
            app.PhngphpDropDown_6Label.Text = 'Phương pháp';
            app.PhngphpDropDown_6 = uidropdown(app.TchphnTab);
            app.PhngphpDropDown_6.Items = {'Hình thang', 'Simpson 1/3', 'Simpson 3/8'};
            app.PhngphpDropDown_6.ValueChangedFcn = createCallbackFcn(app, @PhngphpDropDown_6ValueChanged, true);
            app.PhngphpDropDown_6.Value = 'Hình thang';
            app.CnEditFieldLabel = uilabel(app.TchphnTab);
            app.CnEditFieldLabel.Text = 'Cận';
            app.CnEditField = uieditfield(app.TchphnTab, 'text');
            app.CnEditField.ValueChangedFcn = createCallbackFcn(app, @CnEditFieldValueChanged, true);
            app.NEditFieldLabel = uilabel(app.TchphnTab);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.TchphnTab, 'numeric');
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @NEditFieldValueChanged, true);
            app.KtqucahmsEditField_2Label = uilabel(app.TchphnTab);
            app.KtqucahmsEditField_2Label.Text = 'Kết quả của hàm số';
            app.KtqucahmsEditField_2 = uieditfield(app.TchphnTab, 'numeric');
            app.KtqucahmsEditField_2.ValueChangedFcn = createCallbackFcn(app, @KtqucahmsEditField_2ValueChanged, true);
            app.TnhButton_13 = uibutton(app.TchphnTab, 'push');
            app.TnhButton_13.ButtonPushedFcn = createCallbackFcn(app, @TnhButton_13Pushed, true);
            app.TnhButton_13.Text = 'Tính';
            app.KtqucabngxyEditField_3Label = uilabel(app.TchphnTab);
            app.KtqucabngxyEditField_3Label.Text = 'Kết quả của bảng x,y';
            app.KtqucabngxyEditField_3 = uieditfield(app.TchphnTab, 'numeric');
            app.KtqucabngxyEditField_3.ValueChangedFcn = createCallbackFcn(app, @KtqucabngxyEditField_3ValueChanged, true);
            app.ViphnTab = uitab(app.TabGroup);
            app.ViphnTab.Title = 'Vi phân';
            app.UIAxes3_2 = uiaxes(app.ViphnTab);
            title(app.UIAxes3_2, 'Đồ thị hàm số')
            zlabel(app.UIAxes3_2, 'Z')
            app.PhngtrnhviphnEditField_2Label = uilabel(app.ViphnTab);
            app.PhngtrnhviphnEditField_2Label.Text = 'Phương trình vi phân';
            app.PhngtrnhviphnEditField_2 = uieditfield(app.ViphnTab, 'text');
            app.PhngtrnhviphnEditField_2.ValueChangedFcn = createCallbackFcn(app, @PhngtrnhviphnEditField_2ValueChanged, true);
            app.NghimchnhxcEditField_2Label = uilabel(app.ViphnTab);
            app.NghimchnhxcEditField_2Label.Text = 'Nghiệm chính xác';
            app.NghimchnhxcEditField_2 = uieditfield(app.ViphnTab, 'text');
            app.NghimchnhxcEditField_2.ValueChangedFcn = createCallbackFcn(app, @NghimchnhxcEditField_2ValueChanged, true);
            app.KhonggitrxEditField_2Label = uilabel(app.ViphnTab);
            app.KhonggitrxEditField_2Label.Text = 'Khoảng giá trị x';
            app.KhonggitrxEditField_2 = uieditfield(app.ViphnTab, 'text');
            app.KhonggitrxEditField_2.ValueChangedFcn = createCallbackFcn(app, @KhonggitrxEditField_2ValueChanged, true);
            app.y0EditField_2Label = uilabel(app.ViphnTab);
            app.y0EditField_2Label.Text = 'y0';
            app.y0EditField_2 = uieditfield(app.ViphnTab, 'numeric');
            app.y0EditField_2.ValueChangedFcn = createCallbackFcn(app, @y0EditField_2ValueChanged, true);
            app.NEditField_3Label = uilabel(app.ViphnTab);
            app.NEditField_3Label.Text = 'N';
            app.NEditField_3 = uieditfield(app.ViphnTab, 'numeric');
            app.NEditField_3.ValueChangedFcn = createCallbackFcn(app, @NEditField_3ValueChanged, true);
            app.SaischophpEditField_3Label = uilabel(app.ViphnTab);
            app.SaischophpEditField_3Label.Text = 'Sai số cho phép';
            app.SaischophpEditField_3 = uieditfield(app.ViphnTab, 'numeric');
            app.SaischophpEditField_3.ValueChangedFcn = createCallbackFcn(app, @SaischophpEditField_3ValueChanged, true);
            app.PhngphpDropDown_8Label = uilabel(app.ViphnTab);
            app.PhngphpDropDown_8Label.Text = 'Phương pháp';
            app.PhngphpDropDown_8 = uidropdown(app.ViphnTab);
            app.PhngphpDropDown_8.Items = {'Euler', 'Hình thang', 'Trung điểm', 'Runge-Kutta'};
            app.PhngphpDropDown_8.ValueChangedFcn = createCallbackFcn(app, @PhngphpDropDown_8ValueChanged, true);
            app.PhngphpDropDown_8.Value = 'Hình thang';
            app.TnhButton_15 = uibutton(app.ViphnTab, 'push');
            app.TnhButton_15.ButtonPushedFcn = createCallbackFcn(app, @TnhButton_15Pushed, true);
            app.TnhButton_15.Text = 'Tính';
            app.KtquEditField_4Label = uilabel(app.ViphnTab);
            app.KtquEditField_4Label.Text = 'Kết quả';
            app.KtquEditField_4 = uieditfield(app.ViphnTab, 'text');
            app.KtquEditField_4.ValueChangedFcn = createCallbackFcn(app, @KtquEditField_4ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CK_Nhom

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end