classdef HoiQuy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Heso                         matlab.ui.control.NumericEditField
        Hstngquanr2EditFieldLabel    matlab.ui.control.Label
        Ketquadudoan                 matlab.ui.control.NumericEditField
        KtqudonEditFieldLabel        matlab.ui.control.Label
        Giatricandudoan              matlab.ui.control.NumericEditField
        GitrcndonEditFieldLabel      matlab.ui.control.Label
        Phuongtrinhhoiquy            matlab.ui.control.EditField
        PhngtrnhhiquyEditFieldLabel  matlab.ui.control.Label
        Phuongphap                   matlab.ui.control.DropDown
        PhngphpDropDownLabel         matlab.ui.control.Label
        y                            matlab.ui.control.EditField
        yEditFieldLabel              matlab.ui.control.Label
        x                            matlab.ui.control.EditField
        xEditFieldLabel              matlab.ui.control.Label
        Nuttinh                      matlab.ui.control.Button
        Dothihamso                   matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function [a, b, r2] = linearregress(app, xi, yi)
            p = polyfit(xi, yi, 1);
            b = p(1); a = p(2);
            y_fit = polyval(p, xi);
            r2 = 1 - sum((yi - y_fit).^2) / sum((yi - mean(yi)).^2);
        end

        function [a, b, r2] = xpower(app, xi, yi)
            idx = (xi > 0) & (yi > 0);
            xi_fit = xi(idx);
            yi_fit = yi(idx);
            
            X = log(xi_fit);
            Y = log(yi_fit);

            p = polyfit(X, Y, 1);
            
            b = p(1);         
            a = exp(p(2));    
            
            y_pred = a * (xi_fit.^b);
            SS_res = sum((yi_fit - y_pred).^2);
            SS_tot = sum((yi_fit - mean(yi_fit)).^2);
            r2 = 1 - (SS_res / SS_tot);
        end

        function [a, b, r2] = epower(app, xi, yi)
            idx = (yi > 0);
            xi_clean = xi(idx);
            yi_clean = yi(idx);
            
            X = xi_clean;
            Y = log(yi_clean);
            p = polyfit(X, Y, 1);
            
            b = p(1);
            a = exp(p(2));
            
            y_fit = a * exp(b * xi_clean);
            r2 = 1 - sum((yi_clean - y_fit).^2) / sum((yi_clean - mean(yi_clean)).^2);
        end
    
        function [a, b, r2] = logarit10(app, xi, yi)
            idx = (xi > 0);
            xi_clean = xi(idx);
            yi_clean = yi(idx);
            
            X = log10(xi_clean);
            p = polyfit(X, yi_clean, 1);
            
            b = p(1);
            a = p(2);
            
            y_fit = a + b * log10(xi_clean);
            r2 = 1 - sum((yi_clean - y_fit).^2) / sum((yi_clean - mean(yi_clean)).^2);
        end
    
        function [a, b, r2] = logarite(app, xi, yi)
            idx = (xi > 0);
            xi_clean = xi(idx);
            yi_clean = yi(idx);
            
            X = log(xi_clean);
            p = polyfit(X, yi_clean, 1);
            
            b = p(1);
            a = p(2);
            
            y_fit = a + b * log(xi_clean);
            r2 = 1 - sum((yi_clean - y_fit).^2) / sum((yi_clean - mean(yi_clean)).^2);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Nuttinh
        function NuttinhPushed(app, event)
            
            xi = str2num(app.x.Value);
            yi = str2num(app.y.Value);
            x_input = app.Giatricandudoan.Value; 
        
            if isempty(xi) || isempty(yi) || length(xi) ~= length(yi)
                uialert(app.UIFigure, 'Dữ liệu X và Y phải có cùng độ dài và không được để trống!', 'Lỗi');
                return;
            end
        
            pp = app.Phuongphap.Value;
            a = 0; b = 0; r2 = 0; result = 0; f_str = '';
        
            switch pp
                case "Tuyến tính"
                    [a, b, r2] = app.linearregress(xi, yi);
                    result = a + b * x_input;
                    f_str = sprintf('y = %.4f + %.4f x', a, b);
                    xx = linspace(min(xi), max(xi), 200);
                    yy = a + b * xx;
        
                case "Hàm mũ a*x^b"
                    if any(xi <= 0) || any(yi <= 0)
                        uialert(app.UIFigure, 'x và y phải > 0 đối với hàm lũy thừa', 'Lỗi dữ liệu'); return;
                    end
                    [a, b, r2] = app.xpower(xi, yi);
                    result = a * x_input^b;
                    f_str = sprintf('y = %.4f * x^{%.4f}', a, b);
                    xx = linspace(min(xi), max(xi), 200);
                    yy = a * xx.^b;
        
                case "Hàm mũ a*e^(b*x)"
                    if any(yi <= 0)
                        uialert(app.UIFigure, 'y phải > 0 đối với hàm mũ e', 'Lỗi dữ liệu'); return;
                    end
                    [a, b, r2] = app.epower(xi, yi);
                    result = a * exp(b * x_input);
                    f_str = sprintf('y = %.4f * e^{%.4f x}', a, b);
                    xx = linspace(min(xi), max(xi), 200);
                    yy = a * exp(b * xx);
        
                case "Hàm logarit a + b*log10(x)"
                    if any(xi <= 0)
                        uialert(app.UIFigure, 'x phải > 0 đối với hàm logarit', 'Lỗi dữ liệu'); return;
                    end
                    [a, b, r2] = app.logarit10(xi, yi);
                    result = a + b * log10(x_input);
                    f_str = sprintf('y = %.4f + %.4f log10(x)', a, b);
                    xx = linspace(min(xi), max(xi), 200);
                    yy = a + b * log10(xx);
        
                case "Hàm logarit a + b*ln(x)"
                    if any(xi <= 0)
                        uialert(app.UIFigure, 'x phải > 0 đối với hàm ln', 'Lỗi dữ liệu'); return;
                    end
                    [a, b, r2] = app.logarite(xi, yi);
                    result = a + b * log(x_input);
                    f_str = sprintf('y = %.4f + %.4f ln(x)', a, b);
                    xx = linspace(min(xi), max(xi), 200);
                    yy = a + b * log(xx);
            end
        
            app.Phuongtrinhhoiquy.Value = f_str;
            app.Ketquadudoan.Value = result;
            app.Heso.Value = r2;
        
            cla(app.Dothihamso); 
            plot(app.Dothihamso, xi, yi, 'ob', 'DisplayName', 'Dữ liệu thực');
            hold(app.Dothihamso, 'on');
            plot(app.Dothihamso, xx, yy, '-k', 'LineWidth', 1.5, 'DisplayName', 'Đường hồi quy');
            plot(app.Dothihamso, x_input, result, '*r', 'MarkerSize', 10, 'DisplayName', 'Dự đoán');
            grid(app.Dothihamso, 'on');
            legend(app.Dothihamso, 'show', 'Location', 'best');
            hold(app.Dothihamso, 'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Dothihamso = uiaxes(app.UIFigure);
            title(app.Dothihamso, 'Đồ thị hàm số')
            xlabel(app.Dothihamso, 'x')
            ylabel(app.Dothihamso, 'y')
            zlabel(app.Dothihamso, 'Z')
            app.Nuttinh = uibutton(app.UIFigure, 'push');
            app.Nuttinh.ButtonPushedFcn = createCallbackFcn(app, @NuttinhPushed, true);
            app.Nuttinh.Text = 'Tính';
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.x = uieditfield(app.UIFigure, 'text');
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.y = uieditfield(app.UIFigure, 'text');
            app.PhngphpDropDownLabel = uilabel(app.UIFigure);
            app.PhngphpDropDownLabel.Text = 'Phương pháp';
            app.Phuongphap = uidropdown(app.UIFigure);
            app.Phuongphap.Items = {'Tuyến tính', 'Hàm mũ a*x^b', 'Hàm mũ a*e^(b*x)', 'Hàm logarit a + b*log10(x)', 'Hàm logarit a + b*ln(x)'};
            app.Phuongphap.Value = 'Tuyến tính';
            app.PhngtrnhhiquyEditFieldLabel = uilabel(app.UIFigure);
            app.PhngtrnhhiquyEditFieldLabel.Text = 'Phương trình hồi quy';
            app.Phuongtrinhhoiquy = uieditfield(app.UIFigure, 'text');
            app.GitrcndonEditFieldLabel = uilabel(app.UIFigure);
            app.GitrcndonEditFieldLabel.Text = 'Giá trị cần dự đoán';
            app.Giatricandudoan = uieditfield(app.UIFigure, 'numeric');
            app.KtqudonEditFieldLabel = uilabel(app.UIFigure);
            app.KtqudonEditFieldLabel.Text = 'Kết quả dự đoán';
            app.Ketquadudoan = uieditfield(app.UIFigure, 'numeric');
            app.Hstngquanr2EditFieldLabel = uilabel(app.UIFigure);
            app.Hstngquanr2EditFieldLabel.Text = 'Hệ số tương quan r^2';
            app.Heso = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HoiQuy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end