classdef CK_Nhom < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        TchphnTab               matlab.ui.container.Tab
        GridLayout              matlab.ui.container.GridLayout
        KtquTextArea            matlab.ui.control.TextArea
        KtquLabel               matlab.ui.control.Label
        TchphnLabel             matlab.ui.control.Label
        NEditField              matlab.ui.control.NumericEditField
        NEditFieldLabel         matlab.ui.control.Label
        HocLabel_2              matlab.ui.control.Label
        HmsEditField_2          matlab.ui.control.EditField
        HmsEditField_2Label     matlab.ui.control.Label
        PhngphpDropDown_6       matlab.ui.control.DropDown
        PhngphpDropDown_6Label  matlab.ui.control.Label
        TnhButton_13            matlab.ui.control.Button
        CnEditField             matlab.ui.control.EditField
        CnEditFieldLabel        matlab.ui.control.Label
        yEditField_4            matlab.ui.control.EditField
        yLabel                  matlab.ui.control.Label
        xEditField_4            matlab.ui.control.EditField
        xLabel                  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: xEditField_4
        function xEditField_4ValueChanged(app, event)
                        
        end

        % Value changed function: yEditField_4
        function yEditField_4ValueChanged(app, event)
                       
        end

        % Value changed function: PhngphpDropDown_6
        function PhngphpDropDown_6ValueChanged(app, event)
                       
        end

        % Value changed function: CnEditField
        function CnEditFieldValueChanged(app, event)
                       
        end

        % Value changed function: NEditField
        function NEditFieldValueChanged(app, event)
                       
        end

        % Button pushed function: TnhButton_13
        function TnhButton_13Pushed(app, event)
            strX = app.xEditField_4.Value;
            strY = app.yEditField_4.Value;
            strFunc = app.HmsEditField_2.Value;
            strLimit = app.CnEditField.Value;
            rawN = app.NEditField.Value;
            if ischar(rawN) || isstring(rawN)
                valN = str2double(rawN);
            else
                valN = rawN;
            end
            if isnan(valN) || isempty(valN), valN = 0; end
            method = app.PhngphpDropDown_6.Value;
            I = 0;
            try
                tempX = str2num(strX); %#ok<ST2NM>
                tempY = str2num(strY); %#ok<ST2NM>
                isValidXY = ~isempty(tempX) && ~isempty(tempY) && length(tempX) >= 2;
                if isValidXY
                    X = tempX;
                    Y = tempY;
                    if length(X) ~= length(Y)
                        app.KtquTextArea.Value = "Lỗi: Số lượng phần tử X và Y không bằng nhau!";
                        return;
                    end
                    N = length(X) - 1;
                    h = X(2) - X(1);
                elseif ~isempty(strFunc) && ~isempty(strLimit) && valN > 0
                    limit = str2num(strLimit); %#ok<ST2NM>
                    if length(limit) < 2
                        app.KtquTextArea.Value = "Lỗi: Cận phải nhập 2 số [a b]";
                        return;
                    end
                    a = limit(1);
                    b = limit(2);
                    N = valN;
                    h = (b - a) / N;
                    f = str2func(['@(x) ' strFunc]);
                    X = zeros(1, N + 1);
                    Y = zeros(1, N + 1);
                    for i = 1:(N + 1)
                        X(i) = a + (i-1)*h;
                        Y(i) = f(X(i));
                    end
                else
                    app.KtquTextArea.Value = "Vui lòng nhập liệu 1 trong 2 cách:\n1. Nhập X và Y (ít nhất 2 điểm)\n2. Nhập Hàm số, Cận [a b] và N";
                    return;
                end
                switch method
                    case 'Hình thang'
                        sum_middle = 0;
                        for i = 2:N
                            sum_middle = sum_middle + Y(i);
                        end
                        I = (h/2) * (Y(1) + 2 * sum_middle + Y(end));
                    case 'Simpson 1/3'
                        if mod(N, 2) ~= 0
                            app.KtquTextArea.Value = "Lỗi: Simpson 1/3 yêu cầu N là số CHẴN.";
                            return;
                        end
                        sum_odd = 0; sum_even = 0;
                        for i = 1:(N-1)
                            idx = i + 1;
                            if mod(i, 2) ~= 0; sum_odd = sum_odd + Y(idx);
                            else; sum_even = sum_even + Y(idx); end
                        end
                        I = (h/3) * (Y(1) + 4*sum_odd + 2*sum_even + Y(end));
                    case 'Simpson 3/8'
                        if mod(N, 3) ~= 0
                            app.KtquTextArea.Value = "Lỗi: Simpson 3/8 yêu cầu N chia hết cho 3.";
                            return;
                        end
                        sum_inner = 0;
                        for i = 1:(N-1)
                            idx = i + 1;
                            if mod(i, 3) == 0; sum_inner = sum_inner + 2 * Y(idx);
                            else; sum_inner = sum_inner + 3 * Y(idx); end
                        end
                        I = (3*h/8) * (Y(1) + sum_inner + Y(end));
                end
                app.KtquTextArea.Value = sprintf('Phương pháp: %s\nN = %d\nh = %.4f\nKết quả: %.6f', method, N, h, I);
            catch ME
                app.KtquTextArea.Value = sprintf("Lỗi: %s", string(ME.message));
            end
        end

        % Value changed function: HmsEditField_2
        function HmsEditField_2ValueChanged(app, event)
                        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TchphnTab = uitab(app.TabGroup);
            app.TchphnTab.Title = 'Tích phân';
            app.GridLayout = uigridlayout(app.TchphnTab);
            app.xLabel = uilabel(app.GridLayout);
            app.xLabel.Text = 'x:';
            app.xEditField_4 = uieditfield(app.GridLayout, 'text');
            app.xEditField_4.ValueChangedFcn = createCallbackFcn(app, @xEditField_4ValueChanged, true);
            app.yLabel = uilabel(app.GridLayout);
            app.yLabel.Text = 'y:';
            app.yEditField_4 = uieditfield(app.GridLayout, 'text');
            app.yEditField_4.ValueChangedFcn = createCallbackFcn(app, @yEditField_4ValueChanged, true);
            app.CnEditFieldLabel = uilabel(app.GridLayout);
            app.CnEditFieldLabel.Text = 'Cận';
            app.CnEditField = uieditfield(app.GridLayout, 'text');
            app.CnEditField.ValueChangedFcn = createCallbackFcn(app, @CnEditFieldValueChanged, true);
            app.TnhButton_13 = uibutton(app.GridLayout, 'push');
            app.TnhButton_13.ButtonPushedFcn = createCallbackFcn(app, @TnhButton_13Pushed, true);
            app.TnhButton_13.Text = 'Tính';
            app.PhngphpDropDown_6Label = uilabel(app.GridLayout);
            app.PhngphpDropDown_6Label.Text = 'Phương pháp';
            app.PhngphpDropDown_6 = uidropdown(app.GridLayout);
            app.PhngphpDropDown_6.Items = {'Hình thang', 'Simpson 1/3', 'Simpson 3/8'};
            app.PhngphpDropDown_6.ValueChangedFcn = createCallbackFcn(app, @PhngphpDropDown_6ValueChanged, true);
            app.PhngphpDropDown_6.Value = 'Hình thang';
            app.HmsEditField_2Label = uilabel(app.GridLayout);
            app.HmsEditField_2Label.Text = 'Hàm số';
            app.HmsEditField_2 = uieditfield(app.GridLayout, 'text');
            app.HmsEditField_2.ValueChangedFcn = createCallbackFcn(app, @HmsEditField_2ValueChanged, true);
            app.HocLabel_2 = uilabel(app.GridLayout);
            app.HocLabel_2.Text = 'Hoặc';
            app.NEditFieldLabel = uilabel(app.GridLayout);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.GridLayout, 'numeric');
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @NEditFieldValueChanged, true);
            app.TchphnLabel = uilabel(app.GridLayout);
            app.TchphnLabel.Text = 'Tích phân';
            app.KtquLabel = uilabel(app.GridLayout);
            app.KtquLabel.Text = 'Kết quả:';
            app.KtquTextArea = uitextarea(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CK_Nhom

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end