classdef TimNghiem_QuangKhai < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        TMNGHIMLabel               matlab.ui.control.Label
        KtquPanel                  matlab.ui.container.Panel
        GridLayout5                matlab.ui.container.GridLayout
        SlnlpTextArea              matlab.ui.control.TextArea
        SlnlpTextAreaLabel         matlab.ui.control.Label
        NghimTextArea              matlab.ui.control.TextArea
        NghimLabel                 matlab.ui.control.Label
        ThchinPanel                matlab.ui.container.Panel
        GridLayout4                matlab.ui.container.GridLayout
        TNHButton                  matlab.ui.control.StateButton
        thPanel                    matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        UIAxes                     matlab.ui.control.UIAxes
        uvoPanel                   matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        KhongphnlyEditField        matlab.ui.control.EditField
        KhongphnlyEditFieldLabel   matlab.ui.control.Label
        PhngphpDropDown            matlab.ui.control.DropDown
        PhngphpDropDownLabel       matlab.ui.control.Label
        SaischophpEditField        matlab.ui.control.NumericEditField
        SaischophpEditFieldLabel   matlab.ui.control.Label
        NhpphngtrnhEditField       matlab.ui.control.EditField
        NhpphngtrnhEditFieldLabel  matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)
            app.NhpphngtrnhEditField.Value = 'x^3 - x - 1';
            app.KhongphnlyEditField.Value = '1 2';
            app.SaischophpEditField.Value = 1e-3;
            app.PhngphpDropDown.Value = 'Chia đôi';
        end

        % Value changed function: TNHButton
        function TNHButtonValueChanged(app, event)
            strFunc = app.NhpphngtrnhEditField.Value;
            strRange = app.KhongphnlyEditField.Value;
            tol = app.SaischophpEditField.Value;
            method = app.PhngphpDropDown.Value;
            app.NghimTextArea.Value = '';
            app.SlnlpTextArea.Value = '';
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            grid(app.UIAxes, 'on');
            try
                range = str2num(strRange); %#ok<ST2NM>
                if length(range) ~= 2
                    uialert(app.UIFigure, 'Khoảng phân ly phải nhập 2 số cách nhau bởi dấu cách (VD: 1 2)', 'Lỗi nhập liệu');
                    return;
                end
                a = range(1);
                b = range(2);
                syms x;
                f_sym = str2sym(strFunc);
                f = matlabFunction(f_sym);
                fplot(app.UIAxes, f, [a-1, b+1], 'LineWidth', 1.5);
                yline(app.UIAxes, 0, 'r--', 'LineWidth', 1);
            catch
                uialert(app.UIFigure, 'Hàm số nhập vào không hợp lệ! Hãy kiểm tra lại cú pháp (VD: x^3 - 3*x + 1)', 'Lỗi hàm số');
                return;
            end
            root = NaN;
            iter = 0;
            err_msg = '';
            try
                switch method
                    case 'Chia đôi'
                        [root, iter, err_msg] = app.calculateBisection(f, a, b, tol);
                    case 'Newton'
                        df_sym = diff(f_sym);
                        df = matlabFunction(df_sym);
                        x0 = (a+b)/2;
                        [root, iter, err_msg] = app.calculateNewton(f, df, x0, tol);
                    case 'Lặp'
                        [root, iter, err_msg] = app.calculateFixedPoint(f, a, b, tol);
                end
                if ~isempty(err_msg)
                    uialert(app.UIFigure, err_msg, 'Thông báo tính toán');
                else
                    app.NghimTextArea.Value = sprintf('%.6f', root);
                    app.SlnlpTextArea.Value = sprintf('%d', iter);

                    plot(app.UIAxes, root, f(root), 'ro', 'MarkerSize', 8, 'MarkerFaceColor', 'r');
                    legend(app.UIAxes, 'f(x)', 'y=0', 'Nghiệm');
                end
            catch ME
                uialert(app.UIFigure, ['Lỗi tính toán: ' ME.message], 'Lỗi');
            end
            app.TNHButton.Value = 0;
        end

        % Value changed function: PhngphpDropDown
        function PhngphpDropDownValueChanged(app, event)
            value = app.PhngphpDropDown.Value;
            switch value
                case 'Lặp'
                    app.NhpphngtrnhEditField.Value = '(1+x)^(1/3)';
                    app.NhpphngtrnhEditField.Placeholder = 'Nhập g(x) với x = g(x)';
                case {'Chia đôi', 'Newton'}
                    app.NhpphngtrnhEditField.Value = 'x^3 - x - 1';
                    app.NhpphngtrnhEditField.Placeholder = 'Nhập f(x) với f(x) = 0';
                otherwise
                    app.NhpphngtrnhEditField.Value = '';
            end
            app.NghimTextArea.Value = '';
            app.SlnlpTextArea.Value = '';
            cla(app.UIAxes);
        end
    end
    methods (Access = private)
        % 1. Phương pháp Chia đôi 
        function [root, iter, msg] = calculateBisection(~, f, a, b, tol)
            msg = '';
            iter = 0;
            root = NaN;
            try
                fa = f(a);
                fb = f(b);
            catch
                msg = 'Lỗi tính giá trị hàm tại a, b.';
                return;
            end

            if fa * fb >= 0
                msg = 'Khoảng phân ly không hợp lệ (f(a)*f(b) >= 0).';
                return;
            end

            while (b - a) / 2 > tol
                iter = iter + 1;
                c = (a + b) / 2;
                fc = f(c);
                if fc == 0
                    break;
                end
                if f(a) * fc < 0
                    b = c;
                else
                    a = c;
                end
                if iter > 1000
                    msg = 'Không hội tụ sau 1000 vòng lặp';
                    break;
                end
            end
            root = (a + b) / 2;
        end
        % 2. Phương pháp Newton-Raphson
        function [root, iter, msg] = calculateNewton(~, f, df, x0, tol)
            msg = '';
            iter = 0;
            root = x0;
            max_iter = 1000;

            while iter < max_iter
                fx = f(root);
                dfx = df(root);
                if abs(dfx) < 1e-10
                    msg = 'Đạo hàm quá nhỏ, không thể chia!';
                    return;
                end
                x_new = root - fx / dfx;
                iter = iter + 1;
                if abs(x_new - root) < tol
                    root = x_new;
                    return;
                end
                root = x_new;
            end
            msg = 'Newton không hội tụ sau 1000 vòng lặp';
        end
        % 3. Phương pháp Lặp đơn 
        function [root, iter, msg] = calculateFixedPoint(~, fp, a, b, tol)
            msg = '';
            x0 = (a + b)/2;
            iter = 0;
            x1 = x0;

            for i = 1:1000
                x1 = fp(x0);
                iter = iter + 1;

                if abs(x1 - x0) < tol
                    break;
                end
                x0 = x1;
            end
            root = x1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.uvoPanel = uipanel(app.GridLayout);
            app.uvoPanel.Title = 'Đầu vào';
            app.GridLayout2 = uigridlayout(app.uvoPanel);
            app.NhpphngtrnhEditFieldLabel = uilabel(app.GridLayout2);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình:';
            app.NhpphngtrnhEditField = uieditfield(app.GridLayout2, 'text');
            app.SaischophpEditFieldLabel = uilabel(app.GridLayout2);
            app.SaischophpEditFieldLabel.Text = 'Sai số cho phép:';
            app.SaischophpEditField = uieditfield(app.GridLayout2, 'numeric');
            app.PhngphpDropDownLabel = uilabel(app.GridLayout2);
            app.PhngphpDropDownLabel.Text = 'Phương pháp:';
            app.PhngphpDropDown = uidropdown(app.GridLayout2);
            app.PhngphpDropDown.Items = {'Chia đôi', 'Lặp', 'Newton'};
            app.PhngphpDropDown.ValueChangedFcn = createCallbackFcn(app, @PhngphpDropDownValueChanged, true);
            app.PhngphpDropDown.Value = 'Chia đôi';
            app.KhongphnlyEditFieldLabel = uilabel(app.GridLayout2);
            app.KhongphnlyEditFieldLabel.Text = 'Khoảng phân ly:';
            app.KhongphnlyEditField = uieditfield(app.GridLayout2, 'text');
            app.thPanel = uipanel(app.GridLayout);
            app.thPanel.Title = 'Đồ thị';
            app.GridLayout3 = uigridlayout(app.thPanel);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'Đồ thị')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ThchinPanel = uipanel(app.GridLayout);
            app.ThchinPanel.Title = 'Thực hiện';
            app.GridLayout4 = uigridlayout(app.ThchinPanel);
            app.TNHButton = uibutton(app.GridLayout4, 'state');
            app.TNHButton.ValueChangedFcn = createCallbackFcn(app, @TNHButtonValueChanged, true);
            app.TNHButton.Text = 'TÍNH';
            app.KtquPanel = uipanel(app.GridLayout);
            app.KtquPanel.Title = 'Kết quả';
            app.GridLayout5 = uigridlayout(app.KtquPanel);
            app.NghimLabel = uilabel(app.GridLayout5);
            app.NghimLabel.Text = 'Nghiệm:';
            app.NghimTextArea = uitextarea(app.GridLayout5);
            app.SlnlpTextAreaLabel = uilabel(app.GridLayout5);
            app.SlnlpTextAreaLabel.Text = 'Số lần lặp';
            app.SlnlpTextArea = uitextarea(app.GridLayout5);
            app.TMNGHIMLabel = uilabel(app.GridLayout);
            app.TMNGHIMLabel.Text = 'TìM NGHIỆM';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TimNghiem_QuangKhai

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end